classdef HomePage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GamePagePanel   matlab.ui.container.Panel
        ClickthebuttonbelowtogetstartedLabel  matlab.ui.control.Label
        LetsPlayButton  matlab.ui.control.Button
        CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel  matlab.ui.control.Label
        BrainGameLabel  matlab.ui.control.Label
        Image           matlab.ui.control.Image
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LetsPlayButton
        function LetsPlayButtonPushed(app, event)
            app.ClickthebuttonbelowtogetstartedLabel.Visible = 'off'; % Hide the label
            app.LetsPlayButton.Visible = 'off'; % Hide the button
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Visible = 'off'; % Hide the label
            app.GamePagePanel.Visible = 'on'; % Show the game page panel
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'BackgroundHome.png');
            app.BrainGameLabel = uilabel(app.UIFigure);
            app.BrainGameLabel.Text = 'Brain Game ';
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel = uilabel(app.UIFigure);
            app.CreatedbyKareenaSandhuSalinaMariMentorandMachaelaCruzLabel.Text = 'Created by Kareena Sandhu, Salina-Mari Mentor, and Machaela Cruz';
            app.LetsPlayButton = uibutton(app.UIFigure, 'push');
            app.LetsPlayButton.ButtonPushedFcn = createCallbackFcn(app, @LetsPlayButtonPushed, true);
            app.LetsPlayButton.Text = 'Let''s Play!';
            app.ClickthebuttonbelowtogetstartedLabel = uilabel(app.UIFigure);
            app.ClickthebuttonbelowtogetstartedLabel.Text = 'Click the button below to get started';
            app.GamePagePanel = uipanel(app.UIFigure);
            app.GamePagePanel.Title = 'Game Page';
            app.GamePagePanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HomePage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end